classdef AnsiaRerun110 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        minutiLabel                matlab.ui.control.Label
        DeltatEditField            matlab.ui.control.NumericEditField
        DeltatLabel                matlab.ui.control.Label
        DebugModeCheckBox          matlab.ui.control.CheckBox
        Tree                       matlab.ui.container.CheckBoxTree
        MailListNode               matlab.ui.container.TreeNode
        StefanoLavoroNode          matlab.ui.container.TreeNode
        StefanoPersonaleNode       matlab.ui.container.TreeNode
        LuigiNode                  matlab.ui.container.TreeNode
        MarcoNode                  matlab.ui.container.TreeNode
        SamueleNode                matlab.ui.container.TreeNode
        DamianoNode                matlab.ui.container.TreeNode
        RiccardoNode               matlab.ui.container.TreeNode
        FabioNode                  matlab.ui.container.TreeNode
        DavidNode                  matlab.ui.container.TreeNode
        NicolaNode                 matlab.ui.container.TreeNode
        WelcomeintoLAnsia140Label  matlab.ui.control.Label
        LogTextArea                matlab.ui.control.TextArea
        LogTextAreaLabel           matlab.ui.control.Label
        AvviaButton                matlab.ui.control.Button
    end


    properties (Access = private)
        Timer % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            try
                
                if app.DebugModeCheckBox.Value==0
                    app.Tree.CheckedNodes = [app.StefanoLavoroNode,app.FabioNode,app.SamueleNode,app.RiccardoNode, app.DavidNode, app.LuigiNode, app.NicolaNode];
                else
                    app.Tree.CheckedNodes = [app.StefanoLavoroNode];
                end

                app.AvviaButton.Enable = false;
                app.Timer=setTimer(app);
                start(app.Timer);
            catch err
                err

            end
        end

        % Button pushed function: AvviaButton
        function AvviaButtonPushed(app, event)
            try
                if app.AvviaButton.Text=="Avvia"

                    app.AvviaButton.Enable="off";
                    app.Timer=setTimer(app);
                    app.AvviaButton.Enable="on";
                    app.AvviaButton.Text="Ferma";
                    app.Tree.Enable="off";
                    app.DebugModeCheckBox.Enable="off";
                    app.DeltatEditField.Enable="off";
                    start(app.Timer);

                else

                    app.AvviaButton.Enable="off";
                    stop(app.Timer);
                    app.AvviaButton.Enable="on";
                    app.Tree.Enable="on";
                    app.DebugModeCheckBox.Enable="on";
                    app.DeltatEditField.Enable="on";
                    app.AvviaButton.Text="Avvia";

                end
            catch err
                Obj="DEBUG ANSIA";
                errReport(app,err,Obj);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            try
                stop(app.Timer);
            catch err
                err.message
            end

            delete(app);

        end

        % Value changed function: DebugModeCheckBox
        function DebugModeCheckBoxValueChanged(app, event)
            if app.DebugModeCheckBox.Value==1
                app.Tree.CheckedNodes = [app.StefanoLavoroNode];
            else
                app.Tree.CheckedNodes = [app.StefanoLavoroNode,app.FabioNode,app.SamueleNode,app.RiccardoNode, app.DavidNode; app.LuigiNode, app.NicolaNode];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AvviaButton = uibutton(app.UIFigure, 'push');
            app.AvviaButton.ButtonPushedFcn = createCallbackFcn(app, @AvviaButtonPushed, true);
            app.AvviaButton.Text = 'Avvia';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log:';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.WelcomeintoLAnsia140Label = uilabel(app.UIFigure);
            app.WelcomeintoLAnsia140Label.Text = 'Welcome into L''Ansia 1.4.0';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.MailListNode = uitreenode(app.Tree);
            app.MailListNode.Text = 'Mail List';
            app.StefanoLavoroNode = uitreenode(app.MailListNode);
            app.StefanoLavoroNode.Text = 'Stefano Lavoro';
            app.StefanoPersonaleNode = uitreenode(app.MailListNode);
            app.StefanoPersonaleNode.Text = 'Stefano Personale';
            app.LuigiNode = uitreenode(app.MailListNode);
            app.LuigiNode.Text = 'Luigi';
            app.MarcoNode = uitreenode(app.MailListNode);
            app.MarcoNode.Text = 'Marco';
            app.SamueleNode = uitreenode(app.MailListNode);
            app.SamueleNode.Text = 'Samuele';
            app.DamianoNode = uitreenode(app.MailListNode);
            app.DamianoNode.Text = 'Damiano';
            app.RiccardoNode = uitreenode(app.MailListNode);
            app.RiccardoNode.Text = 'Riccardo';
            app.FabioNode = uitreenode(app.MailListNode);
            app.FabioNode.Text = 'Fabio';
            app.DavidNode = uitreenode(app.MailListNode);
            app.DavidNode.Text = 'David';
            app.NicolaNode = uitreenode(app.MailListNode);
            app.NicolaNode.Text = 'Nicola';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.StefanoLavoroNode];
            app.DebugModeCheckBox = uicheckbox(app.UIFigure);
            app.DebugModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugModeCheckBoxValueChanged, true);
            app.DebugModeCheckBox.Text = 'Debug Mode';
            app.DeltatLabel = uilabel(app.UIFigure);
            app.DeltatLabel.Interpreter = 'latex';
            app.DeltatLabel.Text = '$\Delta t$';
            app.DeltatEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltatEditField.Value = 120;
            app.minutiLabel = uilabel(app.UIFigure);
            app.minutiLabel.Text = 'minuti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnsiaRerun110

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end