classdef Occhio300 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Tree                  matlab.ui.container.CheckBoxTree
        Sezione1Node          matlab.ui.container.TreeNode
        Sezione2Node          matlab.ui.container.TreeNode
        H_2ODropDown          matlab.ui.control.DropDown
        H_2OLabel             matlab.ui.control.Label
        TitleLamp             matlab.ui.control.Lamp
        TitleLampLabel        matlab.ui.control.Label
        MediaAnnualeCheckBox  matlab.ui.control.CheckBox
        OpentheeyeButton      matlab.ui.control.Button
        PVDropDown            matlab.ui.control.DropDown
        PVLabel               matlab.ui.control.Label
        choosingLabel         matlab.ui.control.Label
        welcomeLabel          matlab.ui.control.Label
    end


    properties (Access = public)

        Graph; % Description
        lastRefreshLabel;
        Timer;
        effLabel;
        STLastEta;
        errLabel;
        CurrData;
        CurrName;
        FirstStart;
        chosenPlant;
        Resize;
        CurrPlant;
        ErrCode;
        NErr;
        ErrList;
        BlockData;
        NoLinkBar;
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.CurrPlant="Ponte Giurino";
            app.FirstStart=1;
            app.Resize=0;

        end

        % Button pushed function: OpentheeyeButton
        function OpentheeyeButtonPushed(app, event)

            setEmailOptions();
            app.chosenPlant=app.CurrPlant;
            delete(app.choosingLabel);
            delete(app.welcomeLabel);
            delete(app.PVDropDown);
            delete(app.H_2ODropDown);
            delete(app.H_2OLabel);
            delete(app.PVLabel);
            delete(app.OpentheeyeButton);

            createGraphSpace(app,app.chosenPlant);
            app.Timer=setTimer(app,app.chosenPlant);
            app.NErr(1)=0;
            start(app.Timer);


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            try
                stop(app.Timer);
            end
            delete(app);

        end

        % Value changed function: MediaAnnualeCheckBox
        function MediaAnnualeCheckBoxValueChanged(app, event)
            try
                plotIdro(app,app.CurrData,app.CurrName);
            catch err
                Recipients="stefano.trevisan@zilioservice.com";
                TEXT="Errore in MediaAnnualeCheckBox"+newline+string(err.message);
%                 sendmail2(Recipients,"DEBUG de L'OCCHIO",TEXT{:});
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)

            if app.FirstStart==0
                try
                    cla(app.Graph);
                end

                try

                    createGraphSpace(app,app.chosenPlant);
                    app.Resize=1;
                    RefreshData(app,app.chosenPlant);

                catch err
                    
                    Recipients="stefano.trevisan@zilioservice.com";
                    TEXT="Errore in Resizing'"+newline+string(err.message);
%                     sendmail2(Recipients,"DEBUG de L'OCCHIO",TEXT{:});

                end
                if app.CurrPlant=="SCN - Pilota"
                    l=app.Tree.Position(3);
                    h=app.Tree.Position(4);

                    X=app.Graph.InnerPosition(1);
                    Y=app.Graph.InnerPosition(2);
                    lunghezza=app.Graph.InnerPosition(3);
                    altezza=app.Graph.InnerPosition(4);

                    app.Tree.Position(1)=X;
                    app.Tree.Position(2)=Y+altezza-h;
                    app.Tree.Visible="on";
                end


            end
        end

        % Value changed function: PVDropDown
        function PVDropDownValueChanged(app, event)

            app.H_2ODropDown.BackgroundColor = [0.96,0.96,0.96];
            app.PVDropDown.BackgroundColor = [0.3922 0.8314 0.0745];
            app.CurrPlant=app.PVDropDown.Value;


        end

        % Value changed function: H_2ODropDown
        function H_2ODropDownValueChanged(app, event)

            value = app.H_2ODropDown.Value;
            app.PVDropDown.BackgroundColor = [0.96,0.96,0.96];
            app.H_2ODropDown.BackgroundColor = [0.3922 0.8314 0.0745];
            app.CurrPlant=app.H_2ODropDown.Value;

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            try
                cla(app.Graph);
            end
            if app.CurrPlant=="SCN - Pilota"
                plotSCN(app,app.CurrData);
            end

        end

        % Drop down opening function: PVDropDown
        function PVDropDownOpening(app, event)
                app.PVDropDown.BackgroundColor = [0.3922 0.8314 0.0745];
                app.H_2ODropDown.BackgroundColor = [0.96,0.96,0.96];
                app.CurrPlant=app.PVDropDown.Value;

        end

        % Drop down opening function: H_2ODropDown
        function H_2ODropDownOpening(app, event)
                    app.H_2ODropDown.BackgroundColor = [0.3922 0.8314 0.0745];
                    app.PVDropDown.BackgroundColor = [0.96,0.96,0.96];
                    app.CurrPlant=app.H_2ODropDown.Value;                   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.welcomeLabel = uilabel(app.UIFigure);
            app.welcomeLabel.Text = 'Welcome into Occhio 3.4.0';
            app.choosingLabel = uilabel(app.UIFigure);
            app.choosingLabel.Text = 'Please, choose the plant you want to see';
            app.PVLabel = uilabel(app.UIFigure);
            app.PVLabel.Interpreter = 'latex';
            app.PVLabel.Text = '$PV$:';
            app.PVDropDown = uidropdown(app.UIFigure);
            app.PVDropDown.Items = {'Rubino', 'SCN - Pilota', 'Zilio Group 20 kW'};
            app.PVDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PVDropDownOpening, true);
            app.PVDropDown.ValueChangedFcn = createCallbackFcn(app, @PVDropDownValueChanged, true);
            app.PVDropDown.Value = 'Rubino';
            app.OpentheeyeButton = uibutton(app.UIFigure, 'push');
            app.OpentheeyeButton.ButtonPushedFcn = createCallbackFcn(app, @OpentheeyeButtonPushed, true);
            app.OpentheeyeButton.Text = 'Open the eye';
            app.MediaAnnualeCheckBox = uicheckbox(app.UIFigure);
            app.MediaAnnualeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MediaAnnualeCheckBoxValueChanged, true);
            app.MediaAnnualeCheckBox.Visible = 'off';
            app.TitleLampLabel = uilabel(app.UIFigure);
            app.TitleLampLabel.Visible = 'off';
            app.TitleLampLabel.Text = 'Title';
            app.TitleLamp = uilamp(app.UIFigure);
            app.TitleLamp.Visible = 'off';
            app.H_2OLabel = uilabel(app.UIFigure);
            app.H_2OLabel.Interpreter = 'latex';
            app.H_2OLabel.Text = '$H_{2} O$:';
            app.H_2ODropDown = uidropdown(app.UIFigure);
            app.H_2ODropDown.Items = {'SA3', 'Ponte Giurino', 'San Teodoro', 'Partitore', 'Condotta San Teodoro', 'Torrino Foresta'};
            app.H_2ODropDown.DropDownOpeningFcn = createCallbackFcn(app, @H_2ODropDownOpening, true);
            app.H_2ODropDown.ValueChangedFcn = createCallbackFcn(app, @H_2ODropDownValueChanged, true);
            app.H_2ODropDown.Value = 'Ponte Giurino';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Visible = 'off';
            app.Sezione1Node = uitreenode(app.Tree);
            app.Sezione1Node.Text = 'Sezione 1';
            app.Sezione2Node = uitreenode(app.Tree);
            app.Sezione2Node.Text = 'Sezione 2';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.Sezione1Node, app.Sezione2Node];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Occhio300

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end