classdef viviLOCCHIO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConnectionstateLamp       matlab.ui.control.Lamp
        ConnectionstateLampLabel  matlab.ui.control.Label
        MediaAnnualeCheckBox      matlab.ui.control.CheckBox
        OpentheeyeButton          matlab.ui.control.Button
        PVDropDown                matlab.ui.control.DropDown
        PVLabel                   matlab.ui.control.Label
        choosingLabel             matlab.ui.control.Label
        welcomeLabel              matlab.ui.control.Label
    end


    properties (Access = public)

        Graph; % Description
        lastRefreshLabel;
        Timer;
        effLabel;
        STLastEta;
        errLabel;
        CurrData;
        CurrName;
        FirstStart;
        chosenPlant;
        Resize;
        CurrPlant;
        ErrCode;
        NErr;
        ErrList;
        ann;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.CurrPlant="HONDA";
            app.FirstStart=1;
            app.Resize=0;
            %             app.ErrList=readtable("ErrorList.csv");

        end

        % Button pushed function: OpentheeyeButton
        function OpentheeyeButtonPushed(app, event)

            setEmailOptions();
            app.chosenPlant=app.CurrPlant;
            delete(app.choosingLabel);
            delete(app.welcomeLabel);
            delete(app.PVDropDown);
            delete(app.PVLabel);
            delete(app.OpentheeyeButton);

            createGraphSpace(app,app.chosenPlant);
            app.Timer=setTimer(app,app.chosenPlant);
            app.NErr(1)=0;
            start(app.Timer);


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            try
                stop(app.Timer);
            end
            delete(app);

        end

        % Value changed function: MediaAnnualeCheckBox
        function MediaAnnualeCheckBoxValueChanged(app, event)
            try
                plotIdro(app,app.CurrData,app.CurrName);
            catch err
                Recipients="stefano.trevisan@zilioservice.com";
                TEXT="Errore in MediaAnnualeCheckBox"+newline+string(err.message);
%                 sendmail2(Recipients,"DEBUG de L'OCCHIO",TEXT{:});
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)

            if app.FirstStart==0
                try
                    cla(app.Graph);
                end

                try
                    createGraphSpace(app,app.chosenPlant);
                    app.Resize=1;
                    RefreshData(app,app.chosenPlant);
                catch err
                    Recipients="stefano.trevisan@zilioservice.com";
                    TEXT="Errore in Resizing'"+newline+string(err.message);
%                     sendmail2(Recipients,"DEBUG de L'OCCHIO",TEXT{:});

                end
            end
        end

        % Value changed function: PVDropDown
        function PVDropDownValueChanged(app, event)

            app.PVDropDown.BackgroundColor = [0.3922 0.8314 0.0745];
            app.CurrPlant=app.PVDropDown.Value;


        end

        % Callback function
        function H_2ODropDownValueChanged(app, event)

            value = app.H_2ODropDown.Value;
            app.PVDropDown.BackgroundColor = [0.96,0.96,0.96];
            app.H_2ODropDown.BackgroundColor = [0.3922 0.8314 0.0745];
            app.CurrPlant=app.H_2ODropDown.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.welcomeLabel = uilabel(app.UIFigure);
            app.welcomeLabel.Text = 'Welcome into vivi-L''OCCHIO 0.6.9';
            app.choosingLabel = uilabel(app.UIFigure);
            app.choosingLabel.Text = 'Please, choose the plant you want to see';
            app.PVLabel = uilabel(app.UIFigure);
            app.PVLabel.Interpreter = 'latex';
            app.PVLabel.Text = '$PV$:';
            app.PVDropDown = uidropdown(app.UIFigure);
            app.PVDropDown.Items = {'HONDA', '3F'};
            app.PVDropDown.ValueChangedFcn = createCallbackFcn(app, @PVDropDownValueChanged, true);
            app.PVDropDown.Value = 'HONDA';
            app.OpentheeyeButton = uibutton(app.UIFigure, 'push');
            app.OpentheeyeButton.ButtonPushedFcn = createCallbackFcn(app, @OpentheeyeButtonPushed, true);
            app.OpentheeyeButton.Text = 'Open the eye';
            app.MediaAnnualeCheckBox = uicheckbox(app.UIFigure);
            app.MediaAnnualeCheckBox.ValueChangedFcn = createCallbackFcn(app, @MediaAnnualeCheckBoxValueChanged, true);
            app.MediaAnnualeCheckBox.Visible = 'off';
            app.ConnectionstateLampLabel = uilabel(app.UIFigure);
            app.ConnectionstateLampLabel.Visible = 'off';
            app.ConnectionstateLampLabel.Text = 'Connection state';
            app.ConnectionstateLamp = uilamp(app.UIFigure);
            app.ConnectionstateLamp.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viviLOCCHIO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end